classdef UserInterface24 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        FeedTextArea       matlab.ui.control.TextArea
        FeedTextAreaLabel  matlab.ui.control.Label
        Text               matlab.ui.control.TextArea
        MODELabel          matlab.ui.control.Label
        IDEditField        matlab.ui.control.EditField
        IDEditFieldLabel   matlab.ui.control.Label
        NextButton         matlab.ui.control.Button
        MapPanel           matlab.ui.container.Panel
    end

    
    properties (Access = private)
        init % Whether we've initiated our variables or not
        moveShip % Whether to run or pause the simulation
        frame = 1 % Current frame we're on
        ship % Ship in the geoplot map
        shipPos % Ship's position for every frame
        map % Map plot that is displayed to the participant
        addToUserPath % Whether to add to the uPath or not
        userPath % User inputted path on the geoplot
        patchCmBeforeSurvey % The patch context menu before survey
        patchCmAfterSurvey % The patch context menu after survey
        patches = gobjects(0)% All the patches on the map
        mode = -1 % The current mode, 0-Instructions, 1-Practice, 2-Run1, 3-Run2, 4-Run3
        runs = [load('Run0.mat') load('Run1.mat') load("Run2.mat") load("Run3.mat")] % Region data for each run
        h1Color = "#00FF00" % Color for hazard level 1, green
        h2Color = "#FFFF00" % Color for hazard level 2, yellow
        h3Color = "#FF0000" % Color for hazard level 3, red
        preSelectEdgeColor = [0 0 0] % "#000000" % Edge color of patch before selection
        postSelectEdgeColor = [1 1 1] % "#FFFFFF" % Edge color of patch after selection
        nextRegionToSurvey = 1 % Next region expecting to survey, start from region 1
        DataHeading = ['Region' 'LeftHL' 'LeftULa' 'LeftULb' 'RightHL' 'RightULa' 'RightULb' 'Selection'] % Heading for the data files
        Orders = [0 1 2 3; 0 1 3 2; 0 2 1 3; 0 2 3 1; 0 3 1 2; 0 3 2 1] %possible experiment orders with practice always coming first [ 1, 2, 3, 4, 5, 6 ...]
        % ID1 -> 2, ID2 -> 3, ID3 -> 4, ID4 -> 5, ID5 -> 6. ID6 -> 1, ID7
        % -> 2 ...
        % 0 = practice, 1 = numeric, 2 = colour gradients, 3 = numeric +
        % colour gradients
        participantOrder %the order for this specific participant based on their ID
        runStartTime %time the participant started their last run
        runEndTime %time the participant ended their last run
    end
    
    properties (Access = public)
        
    end
    
    methods (Access = private)

        function LoadNextPage(app)
            if app.mode == -1
                %% Instructions Page
                % SET VISIBLE UI COMPONENTS
                app.FeedTextArea.Visible = "on";
                app.FeedTextAreaLabel.Visible = "off";
                app.Text.Visible = "on";
                app.MODELabel.Visible = "on";
                app.IDEditField.Visible = "on";app.IDEditField.Enable = "on";
                app.IDEditFieldLabel.Visible = "on";
                app.NextButton.Visible = "on";
                app.MapPanel.Visible = "off";

                app.mode = 0;
                app.MODELabel.Text = 'INSTRUCTIONS';
            elseif app.mode == 4
                %% End Page
                if RunComplete(app) == false
                    return
                end

                % SET VISIBLE UI COMPONENTS
                app.FeedTextArea.Visible = "on";
                app.FeedTextAreaLabel.Visible = "on";
                app.Text.Visible = "on";
                app.MODELabel.Visible = "on";
                app.IDEditField.Visible = "on";app.IDEditField.Enable = "off";
                app.IDEditFieldLabel.Visible = "on";
                app.NextButton.Visible = "on";
                app.MapPanel.Visible = "off";

                %save the last run
                SaveCurrentRun(app)
                app.MODELabel.Text = 'END';
            else
                %% Run pages
                if app.mode == 0
                    if length(app.IDEditField.Value) <= 0
                        WriteToFeed(app, "Empty entry is not valid...");
                        return
                    end
                    if isnan(str2double(app.IDEditField.Value))
                        WriteToFeed(app, "Input is not a valid number input...");
                        return
                    end

                    WriteToFeed(app, "Starting practice...");
                    %set the order for the participant using modulus and
                    %the participant ID
                    %where r = IDmod6, participant order, pO = app.Orders(r+1)
                    app.participantOrder = app.Orders(mod(str2double(app.IDEditField.Value),6) + 1,:);
                    pOrder = app.participantOrder
                    app.MODELabel.Text = 'PRACTICE';
                else
                    %do your checks before moving on, return if checks
                    %aren't met
                    if RunComplete(app) == false
                        return
                    end
                    
                    %save run
                    SaveCurrentRun(app)
                    app.MODELabel.Text = strcat('RUN ', num2str(app.mode));
                end
                
                % SET VISIBLE UI COMPONENTS
                app.FeedTextArea.Visible = "on";
                app.FeedTextAreaLabel.Visible = "on";
                app.Text.Visible = "off";
                app.MODELabel.Visible = "on";
                app.IDEditField.Visible = "on";app.IDEditField.Enable = "off";
                app.IDEditFieldLabel.Visible = "on";
                app.NextButton.Visible = "on";
                app.MapPanel.Visible = "on";
                
                app.mode = app.mode + 1;
            
                %reset, so the next region to survey is region 1
                app.nextRegionToSurvey = 1;
    
                %load('ShipPath.mat', 'ShipPosW');
                %app.shipPos = ShipPosW;
    
                %callback functions
                 app.map = geoaxes(app.MapPanel);
                 geobasemap(app.map, "grayland");
                 %removing mapp app callbacks
    %             app.map.ButtonDownFcn = @app.MapClickCallback;
    %             set(app.map.Parent.Parent, 'WindowButtonMotionFcn', @app.AddToUPath);
    
                %context menus
                app.patchCmBeforeSurvey = uicontextmenu(app.map.Parent.Parent);
                app.patchCmAfterSurvey = uicontextmenu(app.map.Parent.Parent);
                %beforeOption1 = uimenu(app.patchCmBeforeSurvey, "Text", "Survey");
                %afterOption1 = uimenu(app.patchCmAfterSurvey, "Text", "Survey");
                afterOption2 = uimenu(app.patchCmAfterSurvey, "Text", "Select");
    
                %assign functions to theselected menu
                %beforeOption1.MenuSelectedFcn = @app.SurveyRegion;
                %afterOption1.MenuSelectedFcn = @app.SurveyRegion;
                afterOption2.MenuSelectedFcn = @app.SelectPatch;
    
                %plot starting ship position
                set(app.map,'NextPlot','add');
                %hiding ship black dot
                %app.ship = geoplot(app.map, app.shipPos(app.frame, 2), app.shipPos(app.frame, 1), 'o', 'MarkerFaceColor', 'k', 'MarkerSize',6,'MarkerEdgeColor','r');
                geolimits(app.map,[47.9 50.1],[-70.1 -64]);
                %leaving map color as black and white
                %geobasemap(app.map, "colorterrain");
    
                %generate ice patches in view
                GenerateIceRegionPatches(app);
                SurveyRegion(app);

                app.runStartTime = datetime("now");
            end
        end

        function RunNextShipFrame(app)
            while app.moveShip
                app.ship.LatitudeData = app.shipPos(app.frame, 2);
                app.ship.LongitudeData = app.shipPos(app.frame, 1 );
                app.frame = app.frame + 1;
                pause(0.03)%important so there's enough time to interrupt the constant calls
            end
        end
        
        function GenerateIceRegionPatches(app)
            runIndex = app.participantOrder(app.mode) + 1;
            run = app.runs(runIndex);
            Regions = run.Regions;%evalin("base", 'Regions');
            s1 = size(Regions,1);
            regionCount = 1;
            for i = 1:s1
                % Left patch
                lon = Regions(i).LeftSubregion.Longitudes; lon = [lon;lon(1)];
                lat = Regions(i).LeftSubregion.Latitudes; lat = [lat;lat(1)];
                patch = geopolyshape(lat, lon);
                p = geoplot(app.map, patch);
                p.Tag = strcat('R',num2str(regionCount),'/','L');
                app.patches= [app.patches; p];
                p.ContextMenu = app.patchCmBeforeSurvey;
                p.ButtonDownFcn = @app.SetContextMenuTag;
                p.FaceColor = "#808080";

                %Right Patch
                lon = Regions(i).RightSubregion.Longitudes; lon = [lon;lon(1)];
                lat = Regions(i).RightSubregion.Latitudes; lat = [lat;lat(1)];
                patch = geopolyshape(lat, lon);
                p = geoplot(app.map, patch);
                p.Tag = strcat('R',num2str(regionCount),'/','R');
                app.patches= [app.patches; p];
                p.ContextMenu = app.patchCmBeforeSurvey;
                p.ButtonDownFcn = @app.SetContextMenuTag;
                p.FaceColor = "#808080";
                
                regionCount = regionCount + 1;
            end
        end
        
        function SetContextMenuTag(~, varargin)
            %return if we aren't clicking the right mouse buttton
            % left:1 middle:2 right:3
            if varargin{2}.Button ~= 3
                return
            end
            set(varargin{1}.ContextMenu,'Tag',varargin{1}.Tag)
        end

        function MapClickCallback(app, varargin)
            %return if we aren't clicking the left mouse buttton
            % left:1 middle:2 right:3
            if varargin{2}.Button ~= 1
                return
            end
            if app.addToUserPath
                app.addToUserPath = false;
                app.DrawingPathLamp.Color = 'r';
            else
                app.addToUserPath = true;
                app.DrawingPathLamp.Color = 'g';
            end
        end

        function AddToUPath(app, varargin)
            if app.addToUserPath
                lat = app.map.CurrentPoint(1,1);
                lon = app.map.CurrentPoint(1,2);
                p = geoplot(app.map, lat, lon, 'o', 'MarkerFaceColor', 'r', 'MarkerSize',6,'MarkerEdgeColor','k');
                p.ButtonDownFcn = @app.MapClickCallback;%so we can click on the point to toggle dropping new points
                app.userPath = [app.userPath; [lat, lon]];
                path = app.userPath;
                save('userPath','path');
                pause(0.1)%important so there's enough time to interrupt the constant calls
            end
        end

        function SurveyRegion(app, regionNumber, ~)
            WriteToFeed(app, "Surveying Region...");
            runIndex = app.participantOrder(app.mode) + 1;

            if nargin < 2
                regionNumber = app.nextRegionToSurvey;
            end

            regionTagSlash = strcat('R', num2str(regionNumber),'/');
            i = strncmp({app.patches.Tag}, regionTagSlash, strlength(regionTagSlash));%get indices of the patches in the region we right clicked on
%             i = strcmp({app.patches.Tag}, tag)%get index of the patch we right clicked on
            regionPatches = app.patches(i);
            set(regionPatches, 'ContextMenu', app.patchCmAfterSurvey);

            %change right
            %regions = app.runs(app.mode).Regions(regionNumber).LeftSubregion;
            regionSize = size(regionPatches);

            for p = 1:regionSize(1)
                patch = regionPatches(p);
                patchTag = patch.Tag;
                side = extractAfter(patchTag,"/");

                if side == "L"
                    leftPatchData = app.runs(runIndex).Regions(regionNumber).LeftSubregion;
                    hLevel = leftPatchData.HazardLevel;
                    if app.runs(runIndex).Regions(regionNumber).LeftSubregion.Run == 1
                        if hLevel == 1
                            patch.FaceColor = app.h1Color;
                        elseif hLevel == 2
                            patch.FaceColor = app.h2Color;
                        elseif hLevel == 3
                            patch.FaceColor = app.h3Color;
                        else
                            disp("Hazard level not recognized");
                        end
                    else
                        patch.FaceColor = app.runs(runIndex).Regions(regionNumber).LeftSubregion.HazardColour;
                    end
                    patch.FaceAlpha = 1;

                    if app.runs(runIndex).Regions(regionNumber).LeftSubregion.Run == 1 || app.runs(runIndex).Regions(regionNumber).LeftSubregion.Run == 3
                        unCertLatLon = app.runs(runIndex).Regions(regionNumber).LeftSubregion.TextPosition;
                        unCertLat = unCertLatLon(1);
                        unCertLon = unCertLatLon(2);
                        unCert = app.runs(runIndex).Regions(regionNumber).LeftSubregion.UncertaintyText;
    
                        tRot = 0;
    
                        if strcmp(app.runs(runIndex).Regions(regionNumber).LeftSubregion.TextOrientation,"Flipped")
                            tRot = -90;
                        end
    
                        text(app.map, unCertLat, unCertLon, unCert, 'FontSize', 7, 'Rotation', tRot, 'Color', 1 -  app.runs(runIndex).Regions(regionNumber).LeftSubregion.HazardColour);
                    end

                    disp(['Left Face Color: ' num2str(patch.FaceColor)]);
                elseif side == "R"
                    rightPatchData = app.runs(runIndex).Regions(regionNumber).RightSubregion;
                    hLevel = rightPatchData.HazardLevel;
                    if app.runs(runIndex).Regions(regionNumber).RightSubregion.Run == 1
                        if hLevel == 1
                            patch.FaceColor = app.h1Color;
                        elseif hLevel == 2
                            patch.FaceColor = app.h2Color;
                        elseif hLevel == 3
                            patch.FaceColor = app.h3Color;
                        else
                            disp("Hazard level not recognized");
                        end
                    else
                        patch.FaceColor = app.runs(runIndex).Regions(regionNumber).RightSubregion.HazardColour;
                    end
                    patch.FaceAlpha = 1;

                    if app.runs(runIndex).Regions(regionNumber).RightSubregion.Run == 1 || app.runs(runIndex).Regions(regionNumber).RightSubregion.Run == 3
                        unCertLatLon = app.runs(runIndex).Regions(regionNumber).RightSubregion.TextPosition;
                        unCertLat = unCertLatLon(1);
                        unCertLon = unCertLatLon(2);
                        unCert = app.runs(runIndex).Regions(regionNumber).RightSubregion.UncertaintyText;
    
                        tRot = 0;
    
                        if strcmp(app.runs(runIndex).Regions(regionNumber).RightSubregion.TextOrientation,"Flipped")
                            tRot = -90;
                        end
    
                        text(app.map, unCertLat, unCertLon, unCert, 'FontSize', 7, 'Rotation', tRot, 'Color', 1 -  app.runs(runIndex).Regions(regionNumber).RightSubregion.HazardColour);
                    end

                    disp(['Right Face Color: ' num2str(patch.FaceColor)]);
                else
                    disp("Errors with patch tag");
                end
            end

            app.nextRegionToSurvey = app.nextRegionToSurvey + 1;
        end

        function SelectPatch(app, src, ~)
            WriteToFeed(app, "Selecting Patch...");

            srcParent = src.Parent;
            tag = srcParent.Tag;
            tags = split(tag,"/");
            regionTag = tags{1};
            regionTagSlash = strcat(regionTag,'/');

            
            i = strncmp({app.patches.Tag}, regionTagSlash, strlength(regionTagSlash));%get indices of the patches in the region we right clicked on
            regionPatches = app.patches(i);

            %unselect all patches in the region
            set(regionPatches, 'EdgeColor', app.preSelectEdgeColor);
            %remove survey/select dropdown option from region
            set(regionPatches, 'ContextMenu', '');

            %select the patch we want
            i = strcmp({app.patches.Tag}, tag);%get index of the patch we right clicked on
            regionPatches = app.patches(i);
            set(regionPatches, 'EdgeColor', app.postSelectEdgeColor);

            SurveyRegion(app);
        end

        function SaveCurrentRun(app)
            runIndex = app.participantOrder(app.mode) + 1;
            app.runEndTime = datetime("now");
            c = datetime("now")
            %d = date;
            dirName = strcat('ParticipantData/','PID',app.IDEditField.Value);
            mkdir(dirName)
            dataFileDir = strcat(dirName,'/','Run',' ',num2str(runIndex-1),'_','PID',' ',app.IDEditField.Value,'.csv');
            if isfile(dataFileDir)
                disp(['Input file (' dataFileDir ') already exists'])
                return
            end
            dataFile = fopen(dataFileDir,'w');
            fprintf(dataFile, '%s,%s,%s,%s,%s,%s,%s,%s\n', 'Region', 'LeftHL', 'LeftULa', 'LeftULb', 'RightHL', 'RightULa', 'RightULb', 'Selection');
            regions = app.runs(runIndex);
            regionsSize = size(regions.Regions);

            for r = 1:regionsSize(1)
                regionTagSlash = strcat('R',num2str(r),'/');
                i = strncmp({app.patches.Tag}, regionTagSlash, strlength(regionTagSlash));%get indices of the patches in this region

                regionPatches = app.patches(i);
                regionSize = size(regionPatches);
                
                lHl=0;lULa=0;lULb=0;
                rHl=0;rULa=0;rULb=0;
                selectedSide =" ";
                for p = 1:regionSize(1)
                    patch = regionPatches(p);
                    patchTag = patch.Tag;
                    side = extractAfter(patchTag,"/");

                    j = strcmp({app.patches.Tag}, patchTag);%get index of the patch we right clicked on
                    e = app.patches(j).EdgeColor;
                    if e == app.postSelectEdgeColor
                        selectedSide = side;
                    end

                    if side == "L"
                        leftPatchData = app.runs(runIndex).Regions(r).LeftSubregion;
                        
                        lHl = leftPatchData.HazardLevel;
                        lULa = leftPatchData.UncertaintyLevel(1);
                        lULb = leftPatchData.UncertaintyLevel(2);
                    elseif side == "R"
                        rightPatchData = app.runs(runIndex).Regions(r).RightSubregion;
                        
                        rHl = rightPatchData.HazardLevel;
                        rULa = rightPatchData.UncertaintyLevel(1);
                        rULb = rightPatchData.UncertaintyLevel(2);
                    else
                        disp("Errors with patch tag");
                    end
                end
                fprintf(dataFile, '%d,%d,%f,%f,%d,%f,%f,%s\n',r,lHl,lULa,lULb,rHl,rULa,rULb,selectedSide);
            end
            dur = seconds(app.runEndTime - app.runStartTime);
            fprintf(dataFile, '\n,Duration,%s\n',dur);
            fclose(dataFile);
        end
        
        function result = RunComplete(app)
            runIndex = app.participantOrder(app.mode) + 1;
            result = true;

            regions = app.runs(runIndex);
            regionsSize = size(regions.Regions);
            for r = 1:regionsSize(1)
                regionTagSlash = strcat('R',num2str(r),'/');
                i = strncmp({app.patches.Tag}, regionTagSlash, strlength(regionTagSlash));%get indices of the patches in this region

                regionPatches = app.patches(i);
                regionSize = size(regionPatches);

                patchSelected = false;
                for p = 1:regionSize(1)
                    patch = regionPatches(p);
                    patchTag = patch.Tag;

                    j = strcmp({app.patches.Tag}, patchTag);%get index of the patch we right clicked on
                    e = app.patches(j).EdgeColor;
                    patchSelected = (e == app.postSelectEdgeColor) | patchSelected;
                end
                result = patchSelected & result;
            end
            if result == false
                WriteToFeed(app, "INCOMPLETE PATH SURVEY/SELECTION");
            end
        end

        function WriteToFeed(app, text)
            set(app.FeedTextArea, "Value", [app.FeedTextArea.Value; text; ""]);
            scroll(app.FeedTextArea, 'bottom'); % autoscroll to bottom
        end

        function ShuffleRuns(app)
            l = length(app.runs);
            for i = 2:l
                r = randi([i, l]);
                temp = app.runs(r);
                app.runs(r) = app.runs(i);
                app.runs(i) = temp;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            LoadNextPage(app)
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            LoadNextPage(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MapPanel = uipanel(app.UIFigure);
            app.MapPanel.Title = 'Map';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.IDEditFieldLabel = uilabel(app.UIFigure);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.UIFigure, 'text');
            app.MODELabel = uilabel(app.UIFigure);
            app.MODELabel.Text = 'MODE';
            app.Text = uitextarea(app.UIFigure);
            app.Text.Editable = 'off';
            app.Text.Value = {'WELCOME!!!'; ''; '* Autonomous shipping Shore Control Centres (SCCs) will require appropriate graphical user interfaces for operators to adequately monitor remote ships operating in hazardous icy environments.'; ''; '* SCC user interfaces should convey sufficient information about hazards in the remote shipping environment for operators to make the best decisions to safeguard their vessels.'; ''; '* Information about hazards in the remote shipping environment is obtained by sensors and represented as colours on hazard maps.'; ''; '* Hazard measurement systems also convey a level of uncertainty about the information they convey.'; ''; '* Hazard measurement uncertainties range from 0% (completely certain about the information represented) to 100 % (absolutely, completely uncertain about the information).'; ''; '* We are comparing three possible representations of hazard measurement uncertainties by testing how participants make choices about the safety of their vessels when presented with hazard and uncertainties'; ''; '* You will complete this experiment in 3 runs with 66 trials per run. In each trial, you will obtain measurements about the SE. Based on information from a pair of hazard/uncertainty make a decision about the safest place for the vessel.'; ''; '* While in a run, you can go back and forth to make changes to your choices.'; ''; '* Assume throughout that the vessel can be located anywhere '};
            app.FeedTextAreaLabel = uilabel(app.UIFigure);
            app.FeedTextAreaLabel.Text = 'Feed';
            app.FeedTextArea = uitextarea(app.UIFigure);
            app.FeedTextArea.Editable = 'off';
            app.FeedTextArea.Value = {'Welcome....'; ' '};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UserInterface24

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end