classdef PlotDigitizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MessageWindowTextArea       matlab.ui.control.TextArea
        MessageWindowTextAreaLabel  matlab.ui.control.Label
        PlotDigitizerLabel          matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        FileTab                     matlab.ui.container.Tab
        SaveDataButton              matlab.ui.control.Button
        LoadImageButton             matlab.ui.control.Button
        CalibrateTab                matlab.ui.container.Tab
        ymaxButton                  matlab.ui.control.Button
        xmaxButton_3                matlab.ui.control.Button
        yminButton                  matlab.ui.control.Button
        xminButton                  matlab.ui.control.Button
        CalibrateAxesButton         matlab.ui.control.Button
        DigitizeTab                 matlab.ui.container.Tab
        isoValuesEditField          matlab.ui.control.EditField
        isoValuesEditFieldLabel     matlab.ui.control.Label
        DigitizeButton              matlab.ui.control.Button
        FinishButton                matlab.ui.control.Button
        isoSortButton               matlab.ui.control.Button
    end

       
    properties (Access = public)
        i % Description
        X      = 0;             X_d    = 0;  % Original Image, image with data
        Y      = 0;                          % Cropped image
        size_x = 0;             size_y = 0;  % Size of the picture
        x_min  = 0;             x_max  = 0;  % Graph axis
        y_min  = 0;             y_max  = 0;
        ax     = 0;             bx     = 0;  % Linear transform coefs : from location to pixel
        ay     = 0;             by     = 0;
        m_location = 0;         % Mouse location
        data   = 0;             % Data array
        n      = 0;             % and its size
        magnification = 5;      %User input: Magnification factor   % Multiplication factor
        ratio  = 0.2;           %User input: Maginified image size  % Crop size ratio
        i_state = 0;                  %idle state menu
    end

    methods (Access = public)

        %         function [ i ] = Get_Input(app  )
        %             %   This function display menu options and get an input
        %             clc;
        %             fprintf('\t Menu\n');
        %             fprintf('\t\t 1. Open an image file\n');
        %             fprintf('\t\t 2. Calibrate axis\n');
        %             fprintf('\t\t 3. Acquire data manually\n');
        %             fprintf('\t\t 4. Acquire data automatically\n');
        %             fprintf('\t\t 5. View and sort data\n');
        %             fprintf('\t\t 6. Clear data\n');
        %             fprintf('\t\t-1. Exit\n\n');
        %             waitfor(app.UIFigure)
        %             i = input('   Your menu? : ');
        %        end

        %         function [  ] = Control( app,i )
        %             %   This function controls menu
        %             if (i == 1)
        %                 Open_Image(app);
        %             elseif(i == 2)
        %                 Calibrate_Axis
        %             elseif(i == 3)
        %                 Acquire_Data_m;
        %             elseif(i == 4)
        %                 Acquire_Data_a;
        %             elseif(i == 5)
        %                 View_Sort_Data;
        %             elseif(i == 6)
        %                 Clear_Data;
        %                 Display_Image(app);
        %             end
        %         end

        function Display_Image(app)
            %   This function shows an image and croped image
            %   Draw main image
            imagesc([app.x_min, app.x_max], [app.y_min, app.y_max], flipdim(app.X_d,1))
            set(gca,'ydir','normal');
            %   Get mouse location
            set(gcf, 'WindowButtonMotionFcn', 'app.m_location = get(gca, ''CurrentPoint'');');
            Get_Mouse_Location(app);
            %   Obtain cropped image
            [px1, px2, py1, py2] = Get_Crop_Position(app);
            app.Y = app.X_d(px1:px2, py1:py2,:);
            hold on;
            x1 = app.x_max + app.ratio*(app.x_min - app.x_max);
            y1 = app.y_max + app.ratio*(app.y_min - app.y_max);
            imagesc([x1, app.x_max], ...
                [y1, app.y_max], flipdim(app.Y,1));

            %   Plot boarderline for cropped image
            %plot(        (x1 + x_max)/2,        (y1 + y_max)/2, 'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'red')
            plot([1, 1] *(x1 + app.x_max)/2,        [y1,  app.y_max]  , 'black:')
            plot(        [x1,  app.x_max]  , [1, 1]*(y1 + app.y_max)/2, 'black:')
            plot([x1, app.x_max], [y1, y1   ], 'black')
            plot([x1, x1   ], [y1, app.y_max], 'black')
            hold off
            % Temp: Mark data
            if(app.data)
                hold on;
                plot(app.data(:,1), app.data(:,2), 'cyano--', 'linewidth', 1.0)
                hold off
            end
        end


        function [ px1, px2, py1, py2 ] = Get_Crop_Position(app  )
            %   This function calculates crop position where cropped and magnified size of picture is (mapp.ax-min)*ratio
            Lx = app.ratio*(app.x_max-app.x_min)/app.magnification / 2;
            Ly = app.ratio*(app.y_max-app.y_min)/app.magnification / 2;
            px = fix(app.m_location(1,1)*app.ax + app.bx);
            py = fix(app.m_location(1,2)*app.ay + app.by);
            px = min(px, app.size_x - fix(app.ax*Lx));
            px = max(px, 1      + fix(app.ax*Lx));
            py = min(py, app.size_y - fix(-app.ay*Ly));
            py = max(py, 1      + fix(-app.ay*Ly));
            py1 = px - fix(app.ax*Lx);
            py2 = px + fix(app.ax*Lx);
            px1 = py - fix(-app.ay*Ly);
            px2 = py + fix(-app.ay*Ly);
        end

        function [  ] = Mark_Cursor( app,option_ )
            %   This function displays the cursor location
            %   options_: marked cursor color option. True -> Red, False -> Cyan
            %   Transform mouse location to pixel location
            [px, py] = m_loc2pixel(app, app.m_location(1, 1), app.m_location(1, 2));
            %   Marked cursor color
            if(option_)
                RGB_ = [255, 0, 0];
            else
                RGB_ = [0, 255, 255];
            end
            %   Mark cursor on the image
            Mark_Pixel(app,px, py, RGB_);
        end

        function [ px, py ] = m_loc2pixel( app, rx, ry )
            %    This function converts m_location into pixel index

            %   Round pixel data: For consistency
            py = round(rx*app.ax + app.bx);
            px = round(ry*app.ay + app.by);
            %   Make px and py in the picture
            px = max(px, 2);
            px = min(px, app.size_y - 2);
            py = max(py, 2);
            py = min(py, app.size_x - 2);
        end

        function [  ] = Mark_Pixel( app, px, py, RGB_ )
            %   This function displays the cursor location
            %   options_: marked cursor color option. True -> Red, False -> Cyan

            app.ratio = 0.3;

            %   Mark cursor on the image
            for ii = px-1:px+1
                for j = py-1:py+1
                    for k = 1:3
                        app.X_d(ii , j , 1)  = RGB_(1)*app.ratio + app.X_d(ii , j , 1)*(1-app.ratio);
                        app.X_d(ii , j , 2)  = RGB_(2)*app.ratio + app.X_d(ii , j , 2)*(1-app.ratio);
                        app.X_d(ii , j , 3)  = RGB_(3)*app.ratio + app.X_d(ii , j , 3)*(1-app.ratio);
                        app.X_d(ii , py, k)  = 000;
                        app.X_d(px, j , k)  = 000;
                    end
                end
            end

        end


        function Get_Mouse_Location(app, ~, ~ )
            %   This function gets the location of the mouse pointer
            app.m_location = get(gca, 'CurrentPoint');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            %%% MAIN CODE %%%
            clc

           % Initialize environment

            close all
            format long

%             while(i ~= -1)
%                 %i = Get_Input(app);
%                 Control(app,i);
%             end
%             clear i
%             app.MessageWindowTextArea.Value=[">>> To get started, load the plot that you want to digitize. Click the [Load Image] button to continue."]
%             %fprintf('\t\tThe code is terminated\n\n');
%             global data                 % To store global variable
%             data = data + 0;            % To get rid of warning
%             
           %%% Open File %%%
            close all;
            [File_, Path_] = uigetfile( ...
                {'*.jpg;*.jpeg;*.tif;*.gif;*.png;*.bmp', ...
                'All MATLAB Image Files (*.jpg,*.jpeg,*.tif,*.gif,*.png,*.bmp)'; ...
                '*.*', ...
                'All Files (*.*)'}, ...
                'Select image file');
            Path_ = strcat(Path_, File_);
            clear File_
            if(isequal(Path_,0))
                return;
            end
            app.n = length(Path_);
            [app.X, ~] = imread(Path_);
            app.X_d    = app.X;
            tmp    = size(app.X);
            app.size_x = tmp(2);
            app.size_y = tmp(1);
            app.x_min = 0;              app.x_max = app.size_x;
            app.y_min = 0;              app.y_max = app.size_y;
            app.ax     = (app.size_x - 1)/(app.x_max - app.x_min);
            app.ay     = (1 - app.size_y)/(app.y_max - app.y_min);
            app.bx     = app.size_x - app.ax*app.x_max;
            app.by     = app.size_y - app.ay*app.y_min;
            figure(1);
            hFig = figure(1);                           %±×·¡ÇÁ Ã¢ÀÇ À§Ä¡¿Í Å©±âÁ¶Àý
            set(hFig, 'Position', [350 150 800 800])
            clear hFig;
            set(gcf, 'color', 'w');
            Display_Image(app);
            disp('here')
        end

        % Button pushed function: CalibrateAxesButton
        function CalibrateAxesButtonPushed(app, event)
              %   This function defines/re-defines the reference axis
            fprintf('\t\t\tAxis definition\n');
            app.n = 0;
            A = zeros(4, 4);
            b = zeros(4, 1);
            NULL = 'b';
            set(gcf, 'currentchar', NULL);                              % Initialization
            while(app.n ~= 4)
                clc
                fprintf('\t Menu\n');
                fprintf('\t\t 1. Define x reference point\n');
                fprintf('\t\t 2. Define y reference point\n');
                fprintf('\t\t 3. Define x/y ratio\n');
                fprintf('\t\t-1. Return\n');
                fprintf('\t\t\t(%d / 4)\n\n\n', app.n)
                app.i_state = input('   Your menu? : ');
                figure(1);                                  % Focus on the figure
                if(app.i_state == 1)                                  % Get x reference point
                    while(1)
                        c = get(gcf, 'currentchar');        % Get character for control
                        Mark_Cursor(app,0);                     % Mark current mouse cursor to image: cyan
                        Display_Image(app);
                        title(sprintf('Coordinate = (%d, %d)\n%s', app.m_location(1,1), app.m_location(1,2), ...
                            'Press ''A/a'' to select reference point'), 'fontsize', 12);
                        drawnow;
                        app.X_d = app.X;                            % Initialize image

                        if(~length(c)); c = NULL; end      % Prevent null character - Matlab cannot handle this
                        if((c == 'a') || (c == 'A'))        % Select reference point
                            set(gcf, 'currentchar', NULL);  % Reset input

                            [px, py] = m_loc2pixel(app, app.m_location(1, 1), app.m_location(1, 2));
                            rx = (py - app.bx)/app.ax;
                            ry = (px - app.by)/app.ay;
                            Mark_Cursor(app,1);                 % Mark current mouse cursor to image: red
                            Display_Image(app);

                            app.n = app.n + 1;                      % Update matrix index
                            alpha = (rx - app.x_min)/(app.x_max - app.x_min);
                            A(app.n, 1) = 1 - alpha;
                            A(app.n, 2) = alpha;
                            clc;
                            b(app.n)    = input('   Reference x is : ');
                            break;                          % Exit while loop
                        end
                    end
                elseif(app.i_state == 2)                              % Get y reference point
                    while(1)
                        c = get(gcf, 'currentchar');        % Get character for control
                        Mark_Cursor(app,0);                     % Mark current mouse cursor to image: cyan
                        Display_Image(app);
                        title(sprintf('Coordinate = (%d, %d)\n%s', app.m_location(1,1), app.m_location(1,2), ...
                            'Press ''A/a'' to select reference point'), 'fontsize', 12);
                        drawnow;
                        app.X_d = app.X;                            % Initialize image

                        if(~length(c)); c = NULL; end      % Prevent null character - Matlab cannot handle this
                        if((c == 'a') || (c == 'A'))        % Select reference point
                            set(gcf, 'currentchar', NULL);  % Reset input

                            [px, py] = m_loc2pixel(app, app.m_location(1, 1), app.m_location(1, 2));
                            rx = (py - app.bx)/app.ax;
                            ry = (px - app.by)/app.ay;
                            Mark_Cursor(app,1);                 % Mark current mouse cursor to image: red
                            Display_Image(app);

                            app.n = app.n + 1;                      % Update matrix index
                            alpha = (ry - app.y_min)/(app.y_max - app.y_min);
                            A(app.n, 3) = 1 - alpha;
                            A(app.n, 4) = alpha;
                            clc;
                            b(app.n)    = input('   Reference y is : ');
                            break;                          % Exit while loop
                        end
                    end
                elseif(app.i_state == 3)                               % Get scale factor
                    app.n = app.n + 1;
                    ratio_xy = input('   Ratio for x/y is is : ');
                    ratio_xy = ratio_xy * app.size_x/app.size_y;
                    A(app.n, 1) = -1;
                    A(app.n, 2) = +1;
                    A(app.n, 3) = +ratio_xy;
                    A(app.n, 4) = -ratio_xy;
                    b(app.n)    = 0;

                elseif(app.i_state == -1)
                    return;
                end
            end
            if(det(A) == 0)
                fprintf('Failed to calibrate axis.\n');
                pause(1);
                return;
            end
            app.X_d   = app.X;
            x     = A\b;
            app.x_min = x(1);
            app.x_max = x(2);
            app.y_min = x(3);
            app.y_max = x(4);
            app.ax    = (app.size_x - 1)/(app.x_max - app.x_min);
            app.ay    = (1 - app.size_y)/(app.y_max - app.y_min);
            app.bx    = app.size_x - app.ax*app.x_max;
            app.by    = app.size_y - app.ay*app.y_min;
            Display_Image(app);
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FileTab = uitab(app.TabGroup);
            app.FileTab.Title = 'File';
            app.LoadImageButton = uibutton(app.FileTab, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.SaveDataButton = uibutton(app.FileTab, 'push');
            app.SaveDataButton.Text = 'Save Data';
            app.CalibrateTab = uitab(app.TabGroup);
            app.CalibrateTab.Title = 'Calibrate';
            app.CalibrateAxesButton = uibutton(app.CalibrateTab, 'push');
            app.CalibrateAxesButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateAxesButtonPushed, true);
            app.CalibrateAxesButton.Text = 'Calibrate Axes';
            app.xminButton = uibutton(app.CalibrateTab, 'push');
            app.xminButton.Text = 'x min';
            app.yminButton = uibutton(app.CalibrateTab, 'push');
            app.yminButton.Text = 'y min';
            app.xmaxButton_3 = uibutton(app.CalibrateTab, 'push');
            app.xmaxButton_3.Text = 'x max';
            app.ymaxButton = uibutton(app.CalibrateTab, 'push');
            app.ymaxButton.Text = 'y max';
            app.DigitizeTab = uitab(app.TabGroup);
            app.DigitizeTab.Title = 'Digitize';
            app.isoSortButton = uibutton(app.DigitizeTab, 'push');
            app.isoSortButton.Text = 'iso-Sort';
            app.FinishButton = uibutton(app.DigitizeTab, 'push');
            app.FinishButton.Text = 'Finish';
            app.DigitizeButton = uibutton(app.DigitizeTab, 'push');
            app.DigitizeButton.Text = 'Digitize';
            app.isoValuesEditFieldLabel = uilabel(app.DigitizeTab);
            app.isoValuesEditFieldLabel.Text = 'iso-Values';
            app.isoValuesEditField = uieditfield(app.DigitizeTab, 'text');
            app.PlotDigitizerLabel = uilabel(app.UIFigure);
            app.PlotDigitizerLabel.Text = 'Plot Digitizer';
            app.MessageWindowTextAreaLabel = uilabel(app.UIFigure);
            app.MessageWindowTextAreaLabel.Text = 'Message Window';
            app.MessageWindowTextArea = uitextarea(app.UIFigure);
            app.MessageWindowTextArea.Value = {'%%%%%%%%%% PLOT DIGITIZER %%%%%%%%%%'; ''; '>>> Welcome!  To get started, click the [Load Image] button then select the plot you wish to digitize...'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotDigitizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end