classdef UserInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ShipTypeDropDown       matlab.ui.control.DropDown
        ShipTypeDropDownLabel  matlab.ui.control.Label
        MapPanel               matlab.ui.container.Panel
        Image_2                matlab.ui.control.Image
        Image                  matlab.ui.control.Image
        STOPButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        run;
        pause;
        simOut;
        gx;
        shipType = -1;
        simName = 'drone_test_1';
        simIn = Simulink.SimulationInput('drone_test_1');
        initHMap = false;
        ship;
        drone;
    end
    
    methods (Access = public)
        
        function updateTopFeed(app, varargin)
            %call back to update the top video feed
            rto = get_param([bdroot,'/TopDroneFeed'],'RuntimeObject');
            
            app.Image.ImageSource = rto.InputPort(1).Data;
        end
        
        function updateFrontFeed(app, varargin)
            %call back to update the front video feed
            rto = get_param([bdroot,'/FrontDroneFeed'],'RuntimeObject');
            
            app.Image_2.ImageSource = rto.InputPort(1).Data;
        end

        function initHazardMap(app, varargin)
            if(app.initHMap == true)
                return;
            end

            %call back to update the hazard map axes
            rto = get_param([bdroot,'/HazardMapDisplay'],'RuntimeObject');


%             geolimits(app.gx, [48.5 49], [-69 -68]);
            set(app.gx,'NextPlot','add')

            rto = get_param([bdroot,'/HazardMapDisplay'],'RuntimeObject');
            app.ship = geoplot(app.gx, rto.InputPort(1).Data(2), rto.InputPort(1).Data(1), 'o', 'MarkerFaceColor', 'k', 'MarkerSize',6,'MarkerEdgeColor','r');
            app.drone = geoplot(app.gx, rto.InputPort(2).Data(2), rto.InputPort(2).Data(1), 'o', 'MarkerFaceColor', [0,0,1], 'MarkerSize',6,'MarkerEdgeColor',[0,0,0]);
            if rto.InputPort(3).Data == 1
                load('SimEnvironment.mat','Env')
                len = length(Env.IceNumerals);

                geoplot(app.gx,Env.IceNumerals(1).shape,'FaceColor',Env.IceNumerals(1).color,'LineStyle','none');
                geolimits(app.gx,[47.9 50.1],[-70 -65.5]);
                geobasemap colorterrain

                hold on
                for i = 2:len
                    geoplot(app.gx,Env.IceNumerals(i).shape,'FaceColor',Env.IceNumerals(i).color,'LineStyle','none')
                    drawnow
                end
                hold off
            end
            
            app.initHMap = true;
        end
        
        function updateHazardMap(app, varargin)
            rto = get_param([bdroot,'/HazardMapDisplay'],'RuntimeObject');
            app.ship.LatitudeData = rto.InputPort(1).Data(2);
            app.ship.LongitudeData = rto.InputPort(1).Data(1);
            app.drone.LatitudeData = rto.InputPort(2).Data(2);
            app.drone.LongitudeData = rto.InputPort(2).Data(1);
        end
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.gx = geoaxes(app.MapPanel);
        end

        % Callback function
        function ImageClicked(app, event)
            
        end

        % Callback function
        function StartButtonPushed(app, event)
            app.simOut = sim(app.simName);
            app.run = true;
            app.pause = false;
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.run = false;
            app.pause = false;
            %simulink.compiler.stopSimulation('drone_test_1');
            set_param(app.simName, 'SimulationCommand', 'stop');
            delete(app)
        end

        % Value changed function: ShipTypeDropDown
        function OnShipTypeChange(app, event)
            value = app.ShipTypeDropDown.Value;
            switch value
                case "E_Class"
                    app.shipType = 3;
                case "D_Class"
                    app.shipType = 4;
                case "C_Class"
                    app.shipType = 5;
                case "B_Class"
                    app.shipType = 6;
                case "A_Class"
                    app.shipType = 7;
                case "CAC4_Class"
                    app.shipType = 8;
                case "CAC3_Class"
                    app.shipType = 9;
                otherwise
                    app.shipType = -1;
            end
            set_param([app.simName,'/ship'],'Value',num2str(app.shipType));
            
        end

        % Callback function
        function UpdateButtonPushed(app, event)
            cla(app.gx);
            app.initHMap = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.Image = uiimage(app.UIFigure);
            app.Image_2 = uiimage(app.UIFigure);
            app.MapPanel = uipanel(app.UIFigure);
            app.MapPanel.Title = 'Map';
            app.ShipTypeDropDownLabel = uilabel(app.UIFigure);
            app.ShipTypeDropDownLabel.Text = 'Ship Type';
            app.ShipTypeDropDown = uidropdown(app.UIFigure);
            app.ShipTypeDropDown.Items = {'E_Class', 'D_Class', 'C_Class', 'B_Class', 'A_Class', 'CAC4_Class', 'CAC3_Class'};
            app.ShipTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @OnShipTypeChange, true);
            app.ShipTypeDropDown.Value = 'E_Class';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UserInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end