classdef UserInterface2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        DrawingPathLamp       matlab.ui.control.Lamp
        DrawingPathLampLabel  matlab.ui.control.Label
        MoveStopShipButton    matlab.ui.control.Button
        MapPanel              matlab.ui.container.Panel
    end

    
    properties (Access = private)
        init % Whether we've initiated our variables or not
        moveShip % Whether to run or pause the simulation
        frame = 1 % Current frame we're on
        ship % Ship in the geoplot map
        shipPos % Ship's position for every frame
        map % Map plot that is displayed to the participant
        addToUserPath %Whether to add to the uPath or not
        userPath %User inputted path on the geoplot
        patchCmBeforeSurvey % The patch context menu
        patchCmAfterSurvey % The patch context menu after survey
        patches = gobjects(0)% All the patches on the map
    end
    
    properties (Access = public)
        
    end
    
    methods (Access = private)
        function RunNextFrame(app)
            while app.moveShip
                app.ship.LatitudeData = app.shipPos(app.frame, 2);
                app.ship.LongitudeData = app.shipPos(app.frame, 1 );
                app.frame = app.frame + 1;
                pause(0.03)%important so there's enough time to interrupt the constant calls
            end
        end
        
        function GenerateIcePatches(app)
            IceRegions = evalin("base", 'IceRegions');
            s1 = size(IceRegions);
            x = s1(1);
            patchCount = 0;
            for i = 1:x
                s2 = size(IceRegions(i).WorldPts);
                y = s2(1);
                for j = 1:y
                    patchCount = patchCount + 1;
                    lon = IceRegions(i).WorldPts(j).PatchPts(:,1); lon = [lon; lon(1)];
                    lat = IceRegions(i).WorldPts(j).PatchPts(:,2); lat = [lat; lat(1)];
                    patch = geopolyshape(lat, lon);
                    p = geoplot(app.map, patch);
                    p.Tag = strcat('P',num2str(patchCount));
                    app.patches= [app.patches; p];

                    p.ContextMenu = app.patchCmBeforeSurvey;
                    p.ButtonDownFcn = @app.SetContextMenuTag;
                end
            end
        end
        
        function SetContextMenuTag(app, varargin)
            %return if we aren't clicking the right mouse buttton
            % left:1 middle:2 right:3
            if varargin{2}.Button ~= 3
                return
            end
            set(varargin{1}.ContextMenu,'Tag',varargin{1}.Tag)
        end

        function MapClickCallback(app, varargin)
            %return if we aren't clicking the left mouse buttton
            % left:1 middle:2 right:3
            if varargin{2}.Button ~= 1
                return
            end
            if app.addToUserPath
                app.addToUserPath = false;
                app.DrawingPathLamp.Color = 'r';
            else
                app.addToUserPath = true;
                app.DrawingPathLamp.Color = 'g';
            end
            d = "MapClickCallback " + app.addToUserPath
        end

        function AddToUPath(app, varargin)
            if app.addToUserPath
                lat = app.map.CurrentPoint(1,1);
                lon = app.map.CurrentPoint(1,2);
                p = geoplot(app.map, lat, lon, 'o', 'MarkerFaceColor', 'r', 'MarkerSize',6,'MarkerEdgeColor','k');
                p.ButtonDownFcn = @app.MapClickCallback;%so we can click on the point to toggle dropping new points
                app.userPath = [app.userPath; [lat, lon]];
                path = app.userPath;
                save('userPath','path');
                pause(0.1)%important so there's enough time to interrupt the constant calls
            end
        end

        function SurveyRegion(app, src, event)
            disp("Surveying Region")
            srcParent = src.Parent;
            tag = srcParent.Tag;
            i = strcmp({app.patches.Tag}, tag);%get index of the patch we right clicked on
            patch = app.patches(i);
            patch.ContextMenu = app.patchCmAfterSurvey;
        end

        function TravelToRegion(app, src, event)
            srcParent = src.Parent;
            tag = srcParent.Tag;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            d = "startupFcn";
            load('ShipPath.mat', 'ShipPosW');
            app.shipPos = ShipPosW;

            %callback functions
            app.map = geoaxes(app.MapPanel);
            app.map.ButtonDownFcn = @app.MapClickCallback;
            set(app.map.Parent.Parent, 'WindowButtonMotionFcn', @app.AddToUPath);

            %context menus
            app.patchCmBeforeSurvey = uicontextmenu(app.map.Parent.Parent);
            app.patchCmAfterSurvey = uicontextmenu(app.map.Parent.Parent);
            beforeOption1 = uimenu(app.patchCmBeforeSurvey, "Text", "Survey");
            afterOption1 = uimenu(app.patchCmAfterSurvey, "Text", "Survey");
            afterOption2 = uimenu(app.patchCmAfterSurvey, "Text", "Travel");

            %assign functions to theselected menu
            beforeOption1.MenuSelectedFcn = @app.SurveyRegion;
            afterOption1.MenuSelectedFcn = @app.SurveyRegion;
            afterOption2.MenuSelectedFcn = @app.TravelToRegion;

            %plot starting ship position
            set(app.map,'NextPlot','add');
            app.ship = geoplot(app.map, app.shipPos(app.frame, 2), app.shipPos(app.frame, 1), 'o', 'MarkerFaceColor', 'k', 'MarkerSize',6,'MarkerEdgeColor','r');
            geolimits(app.map,[47.9 50.1],[-70 -65.5]);
            geobasemap(app.map, "colorterrain");

            %generate ice patches in view
            GenerateIcePatches(app);
        end

        % Button pushed function: MoveStopShipButton
        function MoveStopShipButtonPushed(app, event)
            if app.moveShip
                app.moveShip = false;
            else
                app.moveShip = true;
                RunNextFrame(app);
            end
            d = "ToggleButtonPushed " + app.moveShip;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MapPanel = uipanel(app.UIFigure);
            app.MapPanel.Title = 'Map';
            app.MoveStopShipButton = uibutton(app.UIFigure, 'push');
            app.MoveStopShipButton.ButtonPushedFcn = createCallbackFcn(app, @MoveStopShipButtonPushed, true);
            app.MoveStopShipButton.Text = 'Move/Stop Ship';
            app.DrawingPathLampLabel = uilabel(app.UIFigure);
            app.DrawingPathLampLabel.Text = 'Drawing Path';
            app.DrawingPathLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UserInterface2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end