classdef UserInterface23 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ToggleButton          matlab.ui.control.Button
        DrawingPathLamp       matlab.ui.control.Lamp
        DrawingPathLampLabel  matlab.ui.control.Label
        MapPanel              matlab.ui.container.Panel
        Button_200            matlab.ui.control.Button
        Button_199            matlab.ui.control.Button
        Button_198            matlab.ui.control.Button
        Button_197            matlab.ui.control.Button
        Button_196            matlab.ui.control.Button
        Button_195            matlab.ui.control.Button
        Button_194            matlab.ui.control.Button
        Button_193            matlab.ui.control.Button
        Button_192            matlab.ui.control.Button
        Button_191            matlab.ui.control.Button
        Button_190            matlab.ui.control.Button
        Button_189            matlab.ui.control.Button
        Button_188            matlab.ui.control.Button
        Button_187            matlab.ui.control.Button
        Button_186            matlab.ui.control.Button
        Button_185            matlab.ui.control.Button
        Button_184            matlab.ui.control.Button
        Button_183            matlab.ui.control.Button
        Button_182            matlab.ui.control.Button
        Button_181            matlab.ui.control.Button
        Button_150            matlab.ui.control.Button
        Button_149            matlab.ui.control.Button
        Button_148            matlab.ui.control.Button
        Button_147            matlab.ui.control.Button
        Button_146            matlab.ui.control.Button
        Button_145            matlab.ui.control.Button
        Button_144            matlab.ui.control.Button
        Button_143            matlab.ui.control.Button
        Button_142            matlab.ui.control.Button
        Button_141            matlab.ui.control.Button
        Button_140            matlab.ui.control.Button
        Button_139            matlab.ui.control.Button
        Button_138            matlab.ui.control.Button
        Button_137            matlab.ui.control.Button
        Button_136            matlab.ui.control.Button
        Button_135            matlab.ui.control.Button
        Button_134            matlab.ui.control.Button
        Button_133            matlab.ui.control.Button
        Button_132            matlab.ui.control.Button
        Button_131            matlab.ui.control.Button
        Button_130            matlab.ui.control.Button
        Button_129            matlab.ui.control.Button
        Button_128            matlab.ui.control.Button
        Button_127            matlab.ui.control.Button
        Button_126            matlab.ui.control.Button
        Button_125            matlab.ui.control.Button
        Button_124            matlab.ui.control.Button
        Button_123            matlab.ui.control.Button
        Button_122            matlab.ui.control.Button
        Button_121            matlab.ui.control.Button
        Button_120            matlab.ui.control.Button
        Button_119            matlab.ui.control.Button
        Button_118            matlab.ui.control.Button
        Button_117            matlab.ui.control.Button
        Button_116            matlab.ui.control.Button
        Button_115            matlab.ui.control.Button
        Button_114            matlab.ui.control.Button
        Button_113            matlab.ui.control.Button
        Button_112            matlab.ui.control.Button
        Button_111            matlab.ui.control.Button
        Button_110            matlab.ui.control.Button
        Button_109            matlab.ui.control.Button
        Button_108            matlab.ui.control.Button
        Button_107            matlab.ui.control.Button
        Button_106            matlab.ui.control.Button
        Button_105            matlab.ui.control.Button
        Button_104            matlab.ui.control.Button
        Button_103            matlab.ui.control.Button
        Button_102            matlab.ui.control.Button
        Button_101            matlab.ui.control.Button
        Button_100            matlab.ui.control.Button
        Button_99             matlab.ui.control.Button
        Button_98             matlab.ui.control.Button
        Button_97             matlab.ui.control.Button
        Button_96             matlab.ui.control.Button
        Button_95             matlab.ui.control.Button
        Button_94             matlab.ui.control.Button
        Button_93             matlab.ui.control.Button
        Button_92             matlab.ui.control.Button
        Button_91             matlab.ui.control.Button
        Button_90             matlab.ui.control.Button
        Button_89             matlab.ui.control.Button
        Button_88             matlab.ui.control.Button
        Button_87             matlab.ui.control.Button
        Button_86             matlab.ui.control.Button
        Button_85             matlab.ui.control.Button
        Button_84             matlab.ui.control.Button
        Button_83             matlab.ui.control.Button
        Button_82             matlab.ui.control.Button
        Button_81             matlab.ui.control.Button
        Button_80             matlab.ui.control.Button
        Button_79             matlab.ui.control.Button
        Button_78             matlab.ui.control.Button
        Button_77             matlab.ui.control.Button
        Button_76             matlab.ui.control.Button
        Button_75             matlab.ui.control.Button
        Button_74             matlab.ui.control.Button
        Button_73             matlab.ui.control.Button
        Button_72             matlab.ui.control.Button
        Button_71             matlab.ui.control.Button
        Button_70             matlab.ui.control.Button
        Button_69             matlab.ui.control.Button
        Button_68             matlab.ui.control.Button
        Button_67             matlab.ui.control.Button
        Button_66             matlab.ui.control.Button
        Button_65             matlab.ui.control.Button
        Button_64             matlab.ui.control.Button
        Button_63             matlab.ui.control.Button
        Button_62             matlab.ui.control.Button
        Button_61             matlab.ui.control.Button
        Button_60             matlab.ui.control.Button
        Button_59             matlab.ui.control.Button
        Button_58             matlab.ui.control.Button
        Button_57             matlab.ui.control.Button
        Button_56             matlab.ui.control.Button
        Button_55             matlab.ui.control.Button
        Button_54             matlab.ui.control.Button
        Button_53             matlab.ui.control.Button
        Button_52             matlab.ui.control.Button
        Button_51             matlab.ui.control.Button
        Button_50             matlab.ui.control.Button
        Button_49             matlab.ui.control.Button
        Button_48             matlab.ui.control.Button
        Button_47             matlab.ui.control.Button
        Button_46             matlab.ui.control.Button
        Button_45             matlab.ui.control.Button
        Button_44             matlab.ui.control.Button
        Button_43             matlab.ui.control.Button
        Button_42             matlab.ui.control.Button
        Button_41             matlab.ui.control.Button
        Button_40             matlab.ui.control.Button
        Button_39             matlab.ui.control.Button
    end

    
    properties (Access = private)
        init % Whether we've initiated our variables or not
        moveShip % Whether to run or pause the simulation
        frame = 1 % Current frame we're on
        ship % Ship in the geoplot map
        shipPos % Ship's position for every frame
        map % Map plot that is displayed to the participant
        addToUserPath %Whether to add to the uPath or not
        userPath %User inputted path on the geoplot
        RightBoundary  % map's Right boundary
        LeftBoundary %map's left boundary
    end
    
    properties (Access = public)
        
    end
    
    methods (Access = private)
        function RunNextFrame(app)
            while app.moveShip
                app.ship.LatitudeData = app.shipPos(app.frame, 2);
                app.ship.LongitudeData = app.shipPos(app.frame, 1 );
                app.frame = app.frame + 1;
                pause(0.03)%important so there's enough time to interrupt the constant calls
            end
        end
        
        function GenerateIcePatches(app)
%             IceRegions = evalin("base", 'IceRegions');
%             s1 = size(IceRegions);
%             x = s1(1);
%             
%             for i = 1:x
%                 s2 = size(IceRegions(i).WorldPts);
%                 y = s2(1);
%                 for j = 1:y
%                     lon = IceRegions(i).WorldPts(j).PatchPts(:,1); lon = [lon; lon(1)];
%                     lat = IceRegions(i).WorldPts(j).PatchPts(:,2); lat = [lat; lat(1)];
%                     patch = geopolyshape(lat, lon);
%                     geoplot(app.map, patch);
%                 end
%             end
        end
        
        function MapClickCallback(app, varargin)
            
            if app.addToUserPath
                app.addToUserPath = false;
                app.DrawingPathLamp.Color = 'r';
            else
                app.addToUserPath = true;
                app.DrawingPathLamp.Color = 'g';
                AddToUPath(app)
            end
            d = "MapClickCallback " + app.addToUserPath;
        end

        function AddToUPath(app, varargin)
            while app.addToUserPath
                lat = app.map.CurrentPoint(1,1);
                lon = app.map.CurrentPoint(1,2);
                p = geoplot(app.map, lat, lon, 'o', 'MarkerFaceColor', 'r', 'MarkerSize',3,'MarkerEdgeColor','r');
                pos = [lat, lon];
                app.userPath = [app.userPath; pos];
                path = app.userPath;
                save('userPath','path');
                pause(0.03)%important so there's enough time to interrupt the constant calls
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            d = "startupFcn";
            load('ShipPath.mat', 'ShipPosW')
            
            app.shipPos = ShipPosW;

            app.map = geoaxes(app.MapPanel);
            app.map.Position = [0.05,0.05,0.90,0.90];
%             set(app.map, 'ButtonDownFcn', @app.MapClickCallback); 
            set(app.map.Parent.Parent, 'WindowButtonMotionFcn', @app.AddToUPath);
            set(app.map.Parent.Parent, 'WindowButtonDownFcn', @app.MapClickCallback);
            set(app.map.Parent.Parent, 'WindowButtonUpFcn', @app.MapClickCallback);
            set(app.map,'NextPlot','add')
            app.ship = geoplot(app.map, app.shipPos(app.frame, 2), app.shipPos(app.frame, 1), 'o', 'MarkerFaceColor', 'k', 'MarkerSize',6,'MarkerEdgeColor','r');
            geolimits(app.map,[47.9 50.1],[-70 -65.5]);
            geobasemap(app.map, "colorterrain")

            % add boundary points
            load('RightRiverGeoPts.mat')
%             app
            app.RightBoundary = geoplot(app.map, app.shipPos(app.frame, 2), app.shipPos(app.frame, 1), 'o', 'MarkerFaceColor', 'k', 'MarkerSize',6,'MarkerEdgeColor','r');
            %geobasemap colorterrain    

            GenerateIcePatches(app);
        end

        % Button pushed function: ToggleButton
        function ToggleButtonPushed(app, event)
            if app.moveShip
                app.moveShip = false;
            else
                app.moveShip = true;
                RunNextFrame(app)
            end
            d = "ToggleButtonPushed " + app.moveShip;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MapPanel = uipanel(app.UIFigure);
            app.MapPanel.Title = 'Map';
            app.Button_39 = uibutton(app.MapPanel, 'push');
            app.Button_40 = uibutton(app.MapPanel, 'push');
            app.Button_41 = uibutton(app.MapPanel, 'push');
            app.Button_42 = uibutton(app.MapPanel, 'push');
            app.Button_43 = uibutton(app.MapPanel, 'push');
            app.Button_44 = uibutton(app.MapPanel, 'push');
            app.Button_45 = uibutton(app.MapPanel, 'push');
            app.Button_46 = uibutton(app.MapPanel, 'push');
            app.Button_47 = uibutton(app.MapPanel, 'push');
            app.Button_48 = uibutton(app.MapPanel, 'push');
            app.Button_49 = uibutton(app.MapPanel, 'push');
            app.Button_50 = uibutton(app.MapPanel, 'push');
            app.Button_51 = uibutton(app.MapPanel, 'push');
            app.Button_52 = uibutton(app.MapPanel, 'push');
            app.Button_53 = uibutton(app.MapPanel, 'push');
            app.Button_54 = uibutton(app.MapPanel, 'push');
            app.Button_55 = uibutton(app.MapPanel, 'push');
            app.Button_56 = uibutton(app.MapPanel, 'push');
            app.Button_57 = uibutton(app.MapPanel, 'push');
            app.Button_58 = uibutton(app.MapPanel, 'push');
            app.Button_59 = uibutton(app.MapPanel, 'push');
            app.Button_60 = uibutton(app.MapPanel, 'push');
            app.Button_61 = uibutton(app.MapPanel, 'push');
            app.Button_62 = uibutton(app.MapPanel, 'push');
            app.Button_63 = uibutton(app.MapPanel, 'push');
            app.Button_63.Text = '0 - 25 %';
            app.Button_64 = uibutton(app.MapPanel, 'push');
            app.Button_64.Text = '0 - 25 %';
            app.Button_65 = uibutton(app.MapPanel, 'push');
            app.Button_66 = uibutton(app.MapPanel, 'push');
            app.Button_67 = uibutton(app.MapPanel, 'push');
            app.Button_68 = uibutton(app.MapPanel, 'push');
            app.Button_69 = uibutton(app.MapPanel, 'push');
            app.Button_70 = uibutton(app.MapPanel, 'push');
            app.Button_71 = uibutton(app.MapPanel, 'push');
            app.Button_72 = uibutton(app.MapPanel, 'push');
            app.Button_73 = uibutton(app.MapPanel, 'push');
            app.Button_74 = uibutton(app.MapPanel, 'push');
            app.Button_75 = uibutton(app.MapPanel, 'push');
            app.Button_76 = uibutton(app.MapPanel, 'push');
            app.Button_77 = uibutton(app.MapPanel, 'push');
            app.Button_78 = uibutton(app.MapPanel, 'push');
            app.Button_79 = uibutton(app.MapPanel, 'push');
            app.Button_80 = uibutton(app.MapPanel, 'push');
            app.Button_81 = uibutton(app.MapPanel, 'push');
            app.Button_82 = uibutton(app.MapPanel, 'push');
            app.Button_83 = uibutton(app.MapPanel, 'push');
            app.Button_84 = uibutton(app.MapPanel, 'push');
            app.Button_85 = uibutton(app.MapPanel, 'push');
            app.Button_86 = uibutton(app.MapPanel, 'push');
            app.Button_87 = uibutton(app.MapPanel, 'push');
            app.Button_88 = uibutton(app.MapPanel, 'push');
            app.Button_89 = uibutton(app.MapPanel, 'push');
            app.Button_90 = uibutton(app.MapPanel, 'push');
            app.Button_91 = uibutton(app.MapPanel, 'push');
            app.Button_92 = uibutton(app.MapPanel, 'push');
            app.Button_93 = uibutton(app.MapPanel, 'push');
            app.Button_94 = uibutton(app.MapPanel, 'push');
            app.Button_95 = uibutton(app.MapPanel, 'push');
            app.Button_96 = uibutton(app.MapPanel, 'push');
            app.Button_97 = uibutton(app.MapPanel, 'push');
            app.Button_97.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_97.Text = '';
            app.Button_98 = uibutton(app.MapPanel, 'push');
            app.Button_98.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_98.Text = '';
            app.Button_99 = uibutton(app.MapPanel, 'push');
            app.Button_99.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_99.Text = '';
            app.Button_100 = uibutton(app.MapPanel, 'push');
            app.Button_100.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_100.Text = '';
            app.Button_101 = uibutton(app.MapPanel, 'push');
            app.Button_101.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_101.Text = '';
            app.Button_102 = uibutton(app.MapPanel, 'push');
            app.Button_102.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_102.Text = '';
            app.Button_103 = uibutton(app.MapPanel, 'push');
            app.Button_103.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_103.Text = '';
            app.Button_104 = uibutton(app.MapPanel, 'push');
            app.Button_104.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_104.Text = '';
            app.Button_105 = uibutton(app.MapPanel, 'push');
            app.Button_105.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_105.Text = '';
            app.Button_106 = uibutton(app.MapPanel, 'push');
            app.Button_106.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_106.Text = '';
            app.Button_107 = uibutton(app.MapPanel, 'push');
            app.Button_107.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_107.Text = '';
            app.Button_108 = uibutton(app.MapPanel, 'push');
            app.Button_108.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_108.Text = '';
            app.Button_109 = uibutton(app.MapPanel, 'push');
            app.Button_109.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_109.Text = '';
            app.Button_110 = uibutton(app.MapPanel, 'push');
            app.Button_110.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_110.Text = '';
            app.Button_111 = uibutton(app.MapPanel, 'push');
            app.Button_111.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_111.Text = '';
            app.Button_112 = uibutton(app.MapPanel, 'push');
            app.Button_112.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_112.Text = '';
            app.Button_113 = uibutton(app.MapPanel, 'push');
            app.Button_113.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_113.Text = '';
            app.Button_114 = uibutton(app.MapPanel, 'push');
            app.Button_114.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_114.Text = '';
            app.Button_115 = uibutton(app.MapPanel, 'push');
            app.Button_115.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_115.Text = '';
            app.Button_116 = uibutton(app.MapPanel, 'push');
            app.Button_116.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_116.Text = '';
            app.Button_117 = uibutton(app.MapPanel, 'push');
            app.Button_117.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_117.Text = '';
            app.Button_118 = uibutton(app.MapPanel, 'push');
            app.Button_118.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_118.Text = '';
            app.Button_119 = uibutton(app.MapPanel, 'push');
            app.Button_119.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_119.Text = '';
            app.Button_120 = uibutton(app.MapPanel, 'push');
            app.Button_120.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_120.Text = '';
            app.Button_121 = uibutton(app.MapPanel, 'push');
            app.Button_121.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_121.Text = '';
            app.Button_122 = uibutton(app.MapPanel, 'push');
            app.Button_122.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_122.Text = '';
            app.Button_123 = uibutton(app.MapPanel, 'push');
            app.Button_123.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_123.Text = '';
            app.Button_124 = uibutton(app.MapPanel, 'push');
            app.Button_124.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_124.Text = '';
            app.Button_125 = uibutton(app.MapPanel, 'push');
            app.Button_125.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_125.Text = '';
            app.Button_126 = uibutton(app.MapPanel, 'push');
            app.Button_126.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_126.Text = '';
            app.Button_127 = uibutton(app.MapPanel, 'push');
            app.Button_127.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_127.Text = '';
            app.Button_128 = uibutton(app.MapPanel, 'push');
            app.Button_128.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_128.Text = '';
            app.Button_129 = uibutton(app.MapPanel, 'push');
            app.Button_129.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_129.Text = '';
            app.Button_130 = uibutton(app.MapPanel, 'push');
            app.Button_130.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_130.Text = '';
            app.Button_131 = uibutton(app.MapPanel, 'push');
            app.Button_131.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_131.Text = '';
            app.Button_132 = uibutton(app.MapPanel, 'push');
            app.Button_132.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_132.Text = '';
            app.Button_133 = uibutton(app.MapPanel, 'push');
            app.Button_133.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_133.Text = '';
            app.Button_134 = uibutton(app.MapPanel, 'push');
            app.Button_134.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_134.Text = '';
            app.Button_135 = uibutton(app.MapPanel, 'push');
            app.Button_135.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_135.Text = '';
            app.Button_136 = uibutton(app.MapPanel, 'push');
            app.Button_136.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_136.Text = '';
            app.Button_137 = uibutton(app.MapPanel, 'push');
            app.Button_137.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_137.Text = '';
            app.Button_138 = uibutton(app.MapPanel, 'push');
            app.Button_138.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_138.Text = '';
            app.Button_139 = uibutton(app.MapPanel, 'push');
            app.Button_139.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_139.Text = '';
            app.Button_140 = uibutton(app.MapPanel, 'push');
            app.Button_140.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_140.Text = '';
            app.Button_141 = uibutton(app.MapPanel, 'push');
            app.Button_141.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_141.Text = '';
            app.Button_142 = uibutton(app.MapPanel, 'push');
            app.Button_142.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_142.Text = '';
            app.Button_143 = uibutton(app.MapPanel, 'push');
            app.Button_143.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_143.Text = '';
            app.Button_144 = uibutton(app.MapPanel, 'push');
            app.Button_144.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_144.Text = '';
            app.Button_145 = uibutton(app.MapPanel, 'push');
            app.Button_145.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_145.Text = '';
            app.Button_146 = uibutton(app.MapPanel, 'push');
            app.Button_146.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_146.Text = '';
            app.Button_147 = uibutton(app.MapPanel, 'push');
            app.Button_147.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_147.Text = '';
            app.Button_148 = uibutton(app.MapPanel, 'push');
            app.Button_148.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_148.Text = '';
            app.Button_149 = uibutton(app.MapPanel, 'push');
            app.Button_149.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_149.Text = '';
            app.Button_150 = uibutton(app.MapPanel, 'push');
            app.Button_150.Icon = fullfile(pathToMLAPP, 'vrpl_logo_sm.png');
            app.Button_150.Text = '';
            app.Button_181 = uibutton(app.MapPanel, 'push');
            app.Button_182 = uibutton(app.MapPanel, 'push');
            app.Button_183 = uibutton(app.MapPanel, 'push');
            app.Button_184 = uibutton(app.MapPanel, 'push');
            app.Button_185 = uibutton(app.MapPanel, 'push');
            app.Button_186 = uibutton(app.MapPanel, 'push');
            app.Button_187 = uibutton(app.MapPanel, 'push');
            app.Button_188 = uibutton(app.MapPanel, 'push');
            app.Button_189 = uibutton(app.MapPanel, 'push');
            app.Button_190 = uibutton(app.MapPanel, 'push');
            app.Button_191 = uibutton(app.MapPanel, 'push');
            app.Button_192 = uibutton(app.MapPanel, 'push');
            app.Button_193 = uibutton(app.MapPanel, 'push');
            app.Button_194 = uibutton(app.MapPanel, 'push');
            app.Button_195 = uibutton(app.MapPanel, 'push');
            app.Button_196 = uibutton(app.MapPanel, 'push');
            app.Button_197 = uibutton(app.MapPanel, 'push');
            app.Button_198 = uibutton(app.MapPanel, 'push');
            app.Button_199 = uibutton(app.MapPanel, 'push');
            app.Button_200 = uibutton(app.MapPanel, 'push');
            app.DrawingPathLampLabel = uilabel(app.UIFigure);
            app.DrawingPathLampLabel.Text = 'Drawing Path';
            app.DrawingPathLamp = uilamp(app.UIFigure);
            app.ToggleButton = uibutton(app.UIFigure, 'push');
            app.ToggleButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleButtonPushed, true);
            app.ToggleButton.Text = 'Toggle';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UserInterface23

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end