classdef contextMenu_ROI_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ROIContextmenudemoLabel  matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ROIhandles=gobjects(1); % Stores ROI handles, produced in startupFcn
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
         function cxMenuSelectionFcn(app,menu,~)
            % cxMenuSelectionFcn; responds to selecting "ChangeColor" in context menu
            % The context menu is assigned to ROI objects. 
            
            % Get ContextMenu tag
            cmtag = menu.Parent.Tag; 
            assert(~isempty(cmtag),'Failure to assign tag to  context menu.')
            % get handle associated with tag
            handleIdx = strcmp({app.ROIhandles.Tag}, cmtag);
            assert(sum(handleIdx)==1, 'Failture to identify ROI.')
            hRoi = app.ROIhandles(handleIdx); 
            % Change color of ROI
            hRoi.Color = rand(1,3); 
        end
    end
    
    
    methods (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Create context menu
            cm = uicontextmenu(app.UIFigure);
            m1 = uimenu(cm,'Text','ChangeColor','MenuSelectedFcn',@(mn,ad)cxMenuSelectionFcn(app,mn,ad));
            
            % Plot 3 ROIs 
            % Assign unique tags to each ROI.
            hold(app.UIAxes,'on')
            app.ROIhandles(1) = images.roi.Rectangle(app.UIAxes,'Position',[.1 .1 .2 .2],'Tag','R1');
            app.ROIhandles(2) = images.roi.Rectangle(app.UIAxes,'Position',[.5 .5 .1 .2],'Tag','R2');
            app.ROIhandles(3) = images.roi.Rectangle(app.UIAxes,'Position',[.1 .6 .2 .3],'Tag','R3');
            
            % Assign context menu to all ROIs (or any object that has a unique tag)
            % This must come after assigning tags and before adding listeners below.  
            set(app.ROIhandles, 'ContextMenu', cm)
            
            % Add listeners to the ROI objects. 
            % The listening will respond when the ROI obj is clicked (right or left mouse button)
            % When clicked, the context menu tag will be changed to match the ROI's tag.  This will
            % inform the context menu selection function which object evoked it.  
            for i = 1:numel(app.ROIhandles)
                app.ROIhandles(i).UserData = listener(app.ROIhandles(i), 'ROIClicked', ...
                    @(~,~)set(app.ROIhandles(i).ContextMenu,'Tag', app.ROIhandles(i).Tag));
            end
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ROIContextmenudemoLabel = uilabel(app.UIFigure);
            app.ROIContextmenudemoLabel.Text = 'ROI Context menu demo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = contextMenu_ROI_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end